classdef BHZGUI2017 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LatticeGUIUIFigure       matlab.ui.Figure
        WSliderLabel             matlab.ui.control.Label
        WSlider                  matlab.ui.control.Slider
        ASliderLabel             matlab.ui.control.Label
        ASlider                  matlab.ui.control.Slider
        BSliderLabel             matlab.ui.control.Label
        BSlider                  matlab.ui.control.Slider
        CalculateButton          matlab.ui.control.Button
        TimelimitEditFieldLabel  matlab.ui.control.Label
        TimelimitEditField       matlab.ui.control.NumericEditField
        TabGroup                 matlab.ui.container.TabGroup
        CalculationTab           matlab.ui.container.Tab
        EigenvaluePlot           matlab.ui.control.UIAxes
        LatticePlot              matlab.ui.control.UIAxes
        FFTPlot                  matlab.ui.control.UIAxes
        zoomButton               matlab.ui.control.Button
        rescaleButton            matlab.ui.control.Button
        panButton                matlab.ui.control.Button
        saveButton               matlab.ui.control.Button
        PlotoptionDropDownLabel  matlab.ui.control.Label
        PlotoptionDropDown       matlab.ui.control.DropDown
        SweepTab                 matlab.ui.container.Tab
        ReplayButton             matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        MField                   matlab.ui.control.NumericEditField
        Label_3                  matlab.ui.control.Label
        pumpField                matlab.ui.control.NumericEditField
        Label_4                  matlab.ui.control.Label
        lossField                matlab.ui.control.NumericEditField
        betaSliderLabel          matlab.ui.control.Label
        betaSlider               matlab.ui.control.Slider
        Label_5                  matlab.ui.control.Label
        betaField                matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        data % Description
    end

    methods (Access = private)
    
        function  replotEig(app)
            %CALCULATE EIGS
            [app.data.egs,app.data.lattice]=BHZcalculate(app.ASlider.Value,app.WSlider.Value,...
                app.BSlider.Value,app.betaSlider.Value);
            %PLOT
            scatter(app.EigenvaluePlot,real(app.data.egs.values), imag(app.data.egs.values), 'r');
            app.EigenvaluePlot.Children.ButtonDownFcn=...
                createCallbackFcn(app, @eigenvaluePlotCallback, true);
        end
        function replotMode(app)
            Visual.plotEig(app.data.egs,app.data.seig,app.LatticePlot);            
            app.LatticePlot.Children(end).ButtonDownFcn=...
               createCallbackFcn(app, @modePlotCallback, true);
        end
        function replotTime(app)
            if isfield(app.data,'soln')
                value = app.PlotoptionDropDown.Value;
                if strcmp(value,'abs(E(t)|^2')
                    Visual.plotTimeAmp(app.data.soln,app.FFTPlot);
                elseif strcmp(value,'FFT')
                    Visual.plotfft(app.data.soln,0,app.FFTPlot);
                end
            end
        end
        function eigenvaluePlotCallback(app,event)
            %find hitpoint
            points=event.IntersectionPoint;
            hitpnt=abs(real(app.data.egs.values)-points(1))...
                +abs(imag(app.data.egs.values)-points(2));
            [~,index]=min(hitpnt);
            app.data.seig=index;
            disp(index)
            replotMode(app);
        end        
        function modePlotCallback(app,event)
            %find hitpoint
            points=event.IntersectionPoint;
            hitpnt=abs([app.data.egs.nodes.x]-points(1))...
                +abs([app.data.egs.nodes.y]-points(2));
            [~,index]=min(hitpnt);
            app.data.snode=index;
            disp(index)
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            include;
            %initial parameters
            app.data.seig=1;
            app.data.snode=1;
            %initial plots
            replotEig(app);
            Visual.showNodes(app.data.lattice,app.LatticePlot);
            app.EigenvaluePlot.Children.ButtonDownFcn=...
                createCallbackFcn(app, @eigenvaluePlotCallback, true);
            app.LatticePlot.Children.ButtonDownFcn=...
                createCallbackFcn(app, @modePlotCallback, true);
        end

        % Value changed function: ASlider, BSlider, WSlider, 
        % betaSlider
        function MSliderValueChanged(app, event)
           % changingValue = event.Value;
           app.MField.Value=app.WSlider.Value;
           app.lossField.Value=app.BSlider.Value;
           app.pumpField.Value=app.ASlider.Value;
           app.betaField.Value=app.betaSlider.Value;
           replotEig(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.data.soln=Solver.calctime(app.data.lattice,app.TimelimitEditField.Value);
            replotTime(app);
            Visual.graphTimeAmp(app.data.soln,1,app.TimelimitEditField.Value,100,app.LatticePlot);
        end

        % Button pushed function: zoomButton
        function zoomButtonPushed(app, event)
            zoom(app.FFTPlot,'on');
        end

        % Button pushed function: rescaleButton
        function rescaleButtonPushed(app, event)
            app.FFTPlot.XLimMode = 'auto';
            app.FFTPlot.YLimMode = 'auto';
        end

        % Button pushed function: panButton
        function panButtonPushed(app, event)
            pan(app.FFTPlot,'on');
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            imputfile
        end

        % Button pushed function: ReplayButton
        function ReplayButtonPushed(app, event)
            Visual.graphTimeAmp(app.data.soln,1,app.TimelimitEditField.Value,100,app.LatticePlot);
        end

        % Value changed function: MField, betaField, lossField, 
        % pumpField
        function MFieldValueChanged(app, event)
           app.WSlider.Value=app.MField.Value;
           app.alphaSlider.Value=app.alphaField.Value;
           app.BSlider.Value=app.lossField.Value;
           app.ASlider.Value=app.pumpField.Value;
           app.betaSlider.Value=app.betaField.Value;
            replotEig(app);
        end

        % Value changed function: PlotoptionDropDown
        function PlotoptionDropDownValueChanged(app, event)
            replotTime(app);
        end

        % Callback function
        function EdgePumpCheckBoxValueChanged(app, event)
            value = app.EdgePumpCheckBox.Value;
            if value==1
                app.SeparatePumpCheckBox.Enable=1;
            else
                app.SeparatePumpCheckBox.Enable=0;
            end
                MSliderValueChanged(app, event);
            SeparatePumpCheckBoxValueChanged(app, event);
        end

        % Callback function
        function SeparatePumpCheckBoxValueChanged(app, event)
            value = app.SeparatePumpCheckBox.Value;            
            value1 = app.EdgePumpCheckBox.Value;
            if value==1&&value1++1
                app.BulkEdgePumpSlider.Enable=1;
            else
                app.BulkEdgePumpSlider.Enable=0;
            end
                MSliderValueChanged(app, event);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LatticeGUIUIFigure = uifigure;
            app.LatticeGUIUIFigure.Name = 'Lattice GUI';
            app.WSliderLabel = uilabel(app.LatticeGUIUIFigure);
            app.WSliderLabel.Text = 'W';
            app.WSlider = uislider(app.LatticeGUIUIFigure);
            app.WSlider.Limits = [0 10];
            app.WSlider.ValueChangedFcn = createCallbackFcn(app, @MSliderValueChanged, true);
            app.ASliderLabel = uilabel(app.LatticeGUIUIFigure);
            app.ASliderLabel.Text = 'A';
            app.ASlider = uislider(app.LatticeGUIUIFigure);
            app.ASlider.Limits = [0 10];
            app.ASlider.ValueChangedFcn = createCallbackFcn(app, @MSliderValueChanged, true);
            app.BSliderLabel = uilabel(app.LatticeGUIUIFigure);
            app.BSliderLabel.Text = 'B';
            app.BSlider = uislider(app.LatticeGUIUIFigure);
            app.BSlider.Limits = [0 10];
            app.BSlider.ValueChangedFcn = createCallbackFcn(app, @MSliderValueChanged, true);
            app.CalculateButton = uibutton(app.LatticeGUIUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.TimelimitEditFieldLabel = uilabel(app.LatticeGUIUIFigure);
            app.TimelimitEditFieldLabel.Text = 'Timelimit';
            app.TimelimitEditField = uieditfield(app.LatticeGUIUIFigure, 'numeric');
            app.TimelimitEditField.Value = 100;
            app.TabGroup = uitabgroup(app.LatticeGUIUIFigure);
            app.CalculationTab = uitab(app.TabGroup);
            app.CalculationTab.Title = 'Calculation';
            app.EigenvaluePlot = uiaxes(app.CalculationTab);
            title(app.EigenvaluePlot, 'Eigenvalues')
            xlabel(app.EigenvaluePlot, 'Real')
            ylabel(app.EigenvaluePlot, 'Imag')
            app.LatticePlot = uiaxes(app.CalculationTab);
            title(app.LatticePlot, 'Lattice')
            xlabel(app.LatticePlot, 'X')
            ylabel(app.LatticePlot, 'Y')
            app.FFTPlot = uiaxes(app.CalculationTab);
            xlabel(app.FFTPlot, 'Frequency')
            ylabel(app.FFTPlot, 'Abs(FFT(E))')
            app.zoomButton = uibutton(app.CalculationTab, 'push');
            app.zoomButton.ButtonPushedFcn = createCallbackFcn(app, @zoomButtonPushed, true);
            app.zoomButton.Text = '🔎';
            app.rescaleButton = uibutton(app.CalculationTab, 'push');
            app.rescaleButton.ButtonPushedFcn = createCallbackFcn(app, @rescaleButtonPushed, true);
            app.rescaleButton.Text = '💯';
            app.panButton = uibutton(app.CalculationTab, 'push');
            app.panButton.ButtonPushedFcn = createCallbackFcn(app, @panButtonPushed, true);
            app.panButton.Text = '🍳';
            app.saveButton = uibutton(app.CalculationTab, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = '💾';
            app.PlotoptionDropDownLabel = uilabel(app.CalculationTab);
            app.PlotoptionDropDownLabel.Text = 'Plot option';
            app.PlotoptionDropDown = uidropdown(app.CalculationTab);
            app.PlotoptionDropDown.Items = {'FFT', 'abs(E(t)|^2'};
            app.PlotoptionDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotoptionDropDownValueChanged, true);
            app.PlotoptionDropDown.Value = 'FFT';
            app.SweepTab = uitab(app.TabGroup);
            app.SweepTab.Title = 'Sweep';
            app.ReplayButton = uibutton(app.LatticeGUIUIFigure, 'push');
            app.ReplayButton.ButtonPushedFcn = createCallbackFcn(app, @ReplayButtonPushed, true);
            app.ReplayButton.Text = 'Replay';
            app.Label = uilabel(app.LatticeGUIUIFigure);
            app.Label.Text = '';
            app.MField = uieditfield(app.LatticeGUIUIFigure, 'numeric');
            app.MField.ValueChangedFcn = createCallbackFcn(app, @MFieldValueChanged, true);
            app.Label_3 = uilabel(app.LatticeGUIUIFigure);
            app.Label_3.Text = '';
            app.pumpField = uieditfield(app.LatticeGUIUIFigure, 'numeric');
            app.pumpField.ValueChangedFcn = createCallbackFcn(app, @MFieldValueChanged, true);
            app.Label_4 = uilabel(app.LatticeGUIUIFigure);
            app.Label_4.Text = '';
            app.lossField = uieditfield(app.LatticeGUIUIFigure, 'numeric');
            app.lossField.ValueChangedFcn = createCallbackFcn(app, @MFieldValueChanged, true);
            app.betaSliderLabel = uilabel(app.LatticeGUIUIFigure);
            app.betaSliderLabel.Text = 'beta';
            app.betaSlider = uislider(app.LatticeGUIUIFigure);
            app.betaSlider.Limits = [0 1];
            app.betaSlider.ValueChangedFcn = createCallbackFcn(app, @MSliderValueChanged, true);
            app.Label_5 = uilabel(app.LatticeGUIUIFigure);
            app.Label_5.Text = '';
            app.betaField = uieditfield(app.LatticeGUIUIFigure, 'numeric');
            app.betaField.ValueChangedFcn = createCallbackFcn(app, @MFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = BHZGUI2017

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LatticeGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LatticeGUIUIFigure)
        end
    end
end