classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LatticeGUIUIFigure       matlab.ui.Figure
        MSliderLabel             matlab.ui.control.Label
        MSlider                  matlab.ui.control.Slider
        alphaSliderLabel         matlab.ui.control.Label
        alphaSlider              matlab.ui.control.Slider
        pumpSliderLabel          matlab.ui.control.Label
        pumpSlider               matlab.ui.control.Slider
        lossSliderLabel          matlab.ui.control.Label
        lossSlider               matlab.ui.control.Slider
        CalculateButton          matlab.ui.control.Button
        TimelimitEditFieldLabel  matlab.ui.control.Label
        TimelimitEditField       matlab.ui.control.NumericEditField
        TabGroup                 matlab.ui.container.TabGroup
        CalculationTab           matlab.ui.container.Tab
        EigenvaluePlot           matlab.ui.control.UIAxes
        LatticePlot              matlab.ui.control.UIAxes
        FFTPlot                  matlab.ui.control.UIAxes
        zoomButton               matlab.ui.control.Button
        rescaleButton            matlab.ui.control.Button
        panButton                matlab.ui.control.Button
        saveButton               matlab.ui.control.Button
        PlotoptionDropDownLabel  matlab.ui.control.Label
        PlotoptionDropDown       matlab.ui.control.DropDown
        SweepTab                 matlab.ui.container.Tab
        ReplayButton             matlab.ui.control.Button
        SigmaEditFieldLabel      matlab.ui.control.Label
        SigmaEditField           matlab.ui.control.NumericEditField
        TrEditFieldLabel         matlab.ui.control.Label
        TrEditField              matlab.ui.control.NumericEditField
        Label                    matlab.ui.control.Label
        MField                   matlab.ui.control.NumericEditField
        Label_2                  matlab.ui.control.Label
        alphaField               matlab.ui.control.NumericEditField
        Label_3                  matlab.ui.control.Label
        pumpField                matlab.ui.control.NumericEditField
        Label_4                  matlab.ui.control.Label
        lossField                matlab.ui.control.NumericEditField
        EdgePumpCheckBox         matlab.ui.control.CheckBox
        SeparatePumpCheckBox     matlab.ui.control.CheckBox
        BulkEdgePumpSliderLabel  matlab.ui.control.Label
        BulkEdgePumpSlider       matlab.ui.control.Slider
    end


    properties (Access = public)
        data % Description
    end

    methods (Access = private)
    
        function  replotEig(app)
            [app.data.egs,app.data.lattice]=calculate(app.pumpSlider.Value,app.MSlider.Value,...
                app.lossSlider.Value,app.alphaSlider.Value,...
                app.TrEditField.Value,app.EdgePumpCheckBox.Value,...
                app.BulkEdgePumpSlider.Value);
            scatter(app.EigenvaluePlot,real(app.data.egs.values), imag(app.data.egs.values), 'r');
            app.EigenvaluePlot.Children.ButtonDownFcn=...
                createCallbackFcn(app, @eigenvaluePlotCallback, true);
        end
        function replotMode(app)
            Visual.plotEig(app.data.egs,app.data.seig,app.LatticePlot);            
            app.LatticePlot.Children(end).ButtonDownFcn=...
               createCallbackFcn(app, @modePlotCallback, true);
        end
        function replotTime(app)
            if isfield(app.data,'soln')
                value = app.PlotoptionDropDown.Value;
                if strcmp(value,'abs(E(t)|^2')
                    Visual.plotTimeAmp(app.data.soln,app.FFTPlot);
                elseif strcmp(value,'FFT')
                    Visual.plotfft(app.data.soln,0,app.FFTPlot);
                end
            end
        end
        function eigenvaluePlotCallback(app,event)
            %find hitpoint
            points=event.IntersectionPoint;
            hitpnt=abs(real(app.data.egs.values)-points(1))...
                +abs(imag(app.data.egs.values)-points(2));
            [~,index]=min(hitpnt);
            app.data.seig=index;
            disp(index)
            replotMode(app);
        end        
        function modePlotCallback(app,event)
            %find hitpoint
            points=event.IntersectionPoint;
            hitpnt=abs([app.data.egs.nodes.x]-points(1))...
                +abs([app.data.egs.nodes.y]-points(2));
            [~,index]=min(hitpnt);
            app.data.snode=index;
            disp(index)
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            include;
            %initial parameters
            app.data.seig=1;
            app.data.snode=1;
            %initial plots
            replotEig(app);
            Visual.showNodes(app.data.lattice,app.LatticePlot);
            app.EigenvaluePlot.Children.ButtonDownFcn=...
                createCallbackFcn(app, @eigenvaluePlotCallback, true);
            app.LatticePlot.Children.ButtonDownFcn=...
                createCallbackFcn(app, @modePlotCallback, true);
        end

        % Value changed function: BulkEdgePumpSlider, MSlider, 
        % alphaSlider, lossSlider, pumpSlider
        function MSliderValueChanged(app, event)
           % changingValue = event.Value;
           app.MField.Value=app.MSlider.Value;
           app.alphaField.Value=app.alphaSlider.Value;
           app.lossField.Value=app.lossSlider.Value;
           app.pumpField.Value=app.pumpSlider.Value;
           replotEig(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.data.soln=Solver.calctime(app.data.lattice,app.TimelimitEditField.Value);
            replotTime(app);
            Visual.graphTimeAmp(app.data.soln,1,app.TimelimitEditField.Value,100,app.LatticePlot);
        end

        % Button pushed function: zoomButton
        function zoomButtonPushed(app, event)
            zoom(app.FFTPlot,'on');
        end

        % Button pushed function: rescaleButton
        function rescaleButtonPushed(app, event)
            app.FFTPlot.XLimMode = 'auto';
            app.FFTPlot.YLimMode = 'auto';
        end

        % Button pushed function: panButton
        function panButtonPushed(app, event)
            pan(app.FFTPlot,'on');
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            imputfile
        end

        % Button pushed function: ReplayButton
        function ReplayButtonPushed(app, event)
            Visual.graphTimeAmp(app.data.soln,1,app.TimelimitEditField.Value,100,app.LatticePlot);
        end

        % Value changed function: MField, alphaField, lossField, 
        % pumpField
        function MFieldValueChanged(app, event)
           app.MSlider.Value=app.MField.Value;
           app.alphaSlider.Value=app.alphaField.Value;
           app.lossSlider.Value=app.lossField.Value;
           app.pumpSlider.Value=app.pumpField.Value;
            replotEig(app);
        end

        % Value changed function: PlotoptionDropDown
        function PlotoptionDropDownValueChanged(app, event)
            replotTime(app);
        end

        % Value changed function: EdgePumpCheckBox
        function EdgePumpCheckBoxValueChanged(app, event)
            value = app.EdgePumpCheckBox.Value;
            if value==1
                app.SeparatePumpCheckBox.Enable=1;
            else
                app.SeparatePumpCheckBox.Enable=0;
            end
                MSliderValueChanged(app, event);
            SeparatePumpCheckBoxValueChanged(app, event);
        end

        % Value changed function: SeparatePumpCheckBox
        function SeparatePumpCheckBoxValueChanged(app, event)
            value = app.SeparatePumpCheckBox.Value;            
            value1 = app.EdgePumpCheckBox.Value;
            if value==1&&value1++1
                app.BulkEdgePumpSlider.Enable=1;
            else
                app.BulkEdgePumpSlider.Enable=0;
            end
                MSliderValueChanged(app, event);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LatticeGUIUIFigure = uifigure;
            app.LatticeGUIUIFigure.Name = 'Lattice GUI';
            app.MSliderLabel = uilabel(app.LatticeGUIUIFigure);
            app.MSliderLabel.Text = 'M';
            app.MSlider = uislider(app.LatticeGUIUIFigure);
            app.MSlider.Limits = [0 10];
            app.MSlider.ValueChangedFcn = createCallbackFcn(app, @MSliderValueChanged, true);
            app.alphaSliderLabel = uilabel(app.LatticeGUIUIFigure);
            app.alphaSliderLabel.Text = 'alpha';
            app.alphaSlider = uislider(app.LatticeGUIUIFigure);
            app.alphaSlider.Limits = [0 5];
            app.alphaSlider.ValueChangedFcn = createCallbackFcn(app, @MSliderValueChanged, true);
            app.pumpSliderLabel = uilabel(app.LatticeGUIUIFigure);
            app.pumpSliderLabel.Text = 'pump';
            app.pumpSlider = uislider(app.LatticeGUIUIFigure);
            app.pumpSlider.Limits = [0 10];
            app.pumpSlider.ValueChangedFcn = createCallbackFcn(app, @MSliderValueChanged, true);
            app.lossSliderLabel = uilabel(app.LatticeGUIUIFigure);
            app.lossSliderLabel.Text = 'loss';
            app.lossSlider = uislider(app.LatticeGUIUIFigure);
            app.lossSlider.Limits = [0 10];
            app.lossSlider.ValueChangedFcn = createCallbackFcn(app, @MSliderValueChanged, true);
            app.CalculateButton = uibutton(app.LatticeGUIUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.TimelimitEditFieldLabel = uilabel(app.LatticeGUIUIFigure);
            app.TimelimitEditFieldLabel.Text = 'Timelimit';
            app.TimelimitEditField = uieditfield(app.LatticeGUIUIFigure, 'numeric');
            app.TimelimitEditField.Value = 100;
            app.TabGroup = uitabgroup(app.LatticeGUIUIFigure);
            app.CalculationTab = uitab(app.TabGroup);
            app.CalculationTab.Title = 'Calculation';
            app.EigenvaluePlot = uiaxes(app.CalculationTab);
            title(app.EigenvaluePlot, 'Eigenvalues')
            xlabel(app.EigenvaluePlot, 'Real')
            ylabel(app.EigenvaluePlot, 'Imag')
            app.LatticePlot = uiaxes(app.CalculationTab);
            title(app.LatticePlot, 'Lattice')
            xlabel(app.LatticePlot, 'X')
            ylabel(app.LatticePlot, 'Y')
            app.FFTPlot = uiaxes(app.CalculationTab);
            xlabel(app.FFTPlot, 'Frequency')
            ylabel(app.FFTPlot, 'Abs(FFT(E))')
            app.zoomButton = uibutton(app.CalculationTab, 'push');
            app.zoomButton.ButtonPushedFcn = createCallbackFcn(app, @zoomButtonPushed, true);
            app.zoomButton.Text = '🔎';
            app.rescaleButton = uibutton(app.CalculationTab, 'push');
            app.rescaleButton.ButtonPushedFcn = createCallbackFcn(app, @rescaleButtonPushed, true);
            app.rescaleButton.Text = '💯';
            app.panButton = uibutton(app.CalculationTab, 'push');
            app.panButton.ButtonPushedFcn = createCallbackFcn(app, @panButtonPushed, true);
            app.panButton.Text = '🍳';
            app.saveButton = uibutton(app.CalculationTab, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = '💾';
            app.PlotoptionDropDownLabel = uilabel(app.CalculationTab);
            app.PlotoptionDropDownLabel.Text = 'Plot option';
            app.PlotoptionDropDown = uidropdown(app.CalculationTab);
            app.PlotoptionDropDown.Items = {'FFT', 'abs(E(t)|^2'};
            app.PlotoptionDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotoptionDropDownValueChanged, true);
            app.PlotoptionDropDown.Value = 'FFT';
            app.SweepTab = uitab(app.TabGroup);
            app.SweepTab.Title = 'Sweep';
            app.ReplayButton = uibutton(app.LatticeGUIUIFigure, 'push');
            app.ReplayButton.ButtonPushedFcn = createCallbackFcn(app, @ReplayButtonPushed, true);
            app.ReplayButton.Text = 'Replay';
            app.SigmaEditFieldLabel = uilabel(app.LatticeGUIUIFigure);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.LatticeGUIUIFigure, 'numeric');
            app.SigmaEditField.Value = 24;
            app.TrEditFieldLabel = uilabel(app.LatticeGUIUIFigure);
            app.TrEditFieldLabel.Text = 'Tr';
            app.TrEditField = uieditfield(app.LatticeGUIUIFigure, 'numeric');
            app.TrEditField.Value = 0.1;
            app.Label = uilabel(app.LatticeGUIUIFigure);
            app.Label.Text = '';
            app.MField = uieditfield(app.LatticeGUIUIFigure, 'numeric');
            app.MField.ValueChangedFcn = createCallbackFcn(app, @MFieldValueChanged, true);
            app.Label_2 = uilabel(app.LatticeGUIUIFigure);
            app.Label_2.Text = '';
            app.alphaField = uieditfield(app.LatticeGUIUIFigure, 'numeric');
            app.alphaField.ValueChangedFcn = createCallbackFcn(app, @MFieldValueChanged, true);
            app.Label_3 = uilabel(app.LatticeGUIUIFigure);
            app.Label_3.Text = '';
            app.pumpField = uieditfield(app.LatticeGUIUIFigure, 'numeric');
            app.pumpField.ValueChangedFcn = createCallbackFcn(app, @MFieldValueChanged, true);
            app.Label_4 = uilabel(app.LatticeGUIUIFigure);
            app.Label_4.Text = '';
            app.lossField = uieditfield(app.LatticeGUIUIFigure, 'numeric');
            app.lossField.ValueChangedFcn = createCallbackFcn(app, @MFieldValueChanged, true);
            app.EdgePumpCheckBox = uicheckbox(app.LatticeGUIUIFigure);
            app.EdgePumpCheckBox.ValueChangedFcn = createCallbackFcn(app, @EdgePumpCheckBoxValueChanged, true);
            app.EdgePumpCheckBox.Text = 'Edge Pump';
            app.SeparatePumpCheckBox = uicheckbox(app.LatticeGUIUIFigure);
            app.SeparatePumpCheckBox.ValueChangedFcn = createCallbackFcn(app, @SeparatePumpCheckBoxValueChanged, true);
            app.SeparatePumpCheckBox.Enable = 'off';
            app.SeparatePumpCheckBox.Text = 'Separate Pump';
            app.BulkEdgePumpSliderLabel = uilabel(app.LatticeGUIUIFigure);
            app.BulkEdgePumpSliderLabel.Text = 'Bulk/Edge Pump';
            app.BulkEdgePumpSlider = uislider(app.LatticeGUIUIFigure);
            app.BulkEdgePumpSlider.Limits = [0 1];
            app.BulkEdgePumpSlider.ValueChangedFcn = createCallbackFcn(app, @MSliderValueChanged, true);
            app.BulkEdgePumpSlider.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LatticeGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LatticeGUIUIFigure)
        end
    end
end